classdef maskMaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaskMakerUIFigure      matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        LoadMenu               matlab.ui.container.Menu
        SaveMenu               matlab.ui.container.Menu
        ZAPMenu                matlab.ui.container.Menu
        imagePreview           matlab.ui.control.UIAxes
        thresholdValue         matlab.ui.control.Spinner
        thresholdPercent       matlab.ui.control.Label
        ApplyThresholdLabel    matlab.ui.control.Label
        imageHistogram         matlab.ui.control.UIAxes
        ParametersPanel        matlab.ui.container.Panel
        AverageImagesPanel     matlab.ui.container.Panel
        StartLabel             matlab.ui.control.Label
        meanStart              matlab.ui.control.NumericEditField
        StopLabel              matlab.ui.control.Label
        meanStop               matlab.ui.control.NumericEditField
        CreateMaskButtonGroup  matlab.ui.container.ButtonGroup
        ManualButton           matlab.ui.control.RadioButton
        AutoButton             matlab.ui.control.RadioButton
        UpdatePreviewButton    matlab.ui.control.Button
        SubtractBackground     matlab.ui.control.CheckBox
        previewImageButton     matlab.ui.container.ButtonGroup
        MaskButton             matlab.ui.control.RadioButton
        ImageButton            matlab.ui.control.RadioButton
        ImageLabel             matlab.ui.control.Label
        currentFile            matlab.ui.control.EditField
    end

    
    properties (Access = public)
        imageStack % Full image stack loaded
        imageMask  % data for plotting if image mask. Final output
        imageMean  % image that is being converted into an image mask 
    end
    
    methods (Access = public)
        
        function app = updateHistogram(app)
            bins = size(app.imageStack,1);
            cla(app.imageHistogram);
            histogram(app.imageMean, bins, 'parent', app.imageHistogram);
            set(app.imageHistogram, 'XLim', app.thresholdValue.Limits);
            hold(app.imageHistogram, 'on');
        end
    
        function results = updateImageData(app)
            app.imageMean = mean(app.imageStack(:,:,app.meanStart.Value:app.meanStop.Value),3);
            if app.SubtractBackground.Value
                se = strel('disk',3); % will need to add this in as a user option.
                app.imageMean = imtophat(app.imageMean, se);
            end
            % update theshold limits
            x1 = double(floor(min(min(app.imageMean))));
            x2 = double(round(max(max(app.imageMean))));
            app.thresholdValue.Limits = [x1,x2];
        end
        
        function results = updateImagePreview(app)
            if ~app.imagePreview.Visible 
                app.imagePreview.Visible = 1; 
            end
            if app.MaskButton.Value
                imshow(app.imageMask, [],'parent', app.imagePreview)
            elseif app.ImageButton.Value
                imshow(app.imageMean, [],'parent', app.imagePreview)
            end
        end
        
        function app = loadData(app) 
            if isempty(app.currentFile.UserData)
                [file,path] = uigetfile('*.tif');
            else
                path=fileparts(app.currentFile.UserData);
                [file,path] = uigetfile('*.tif','Select Image Stack',path);
            end
            app.imageStack = loadTiffStack([path,file]);
            app.currentFile.UserData = [path,file];
            app.currentFile.Value = file;
            app = initData(app);
            
        end
        
        function app = initData(app)
            updateWindow(app);
            % set limits on values
            [s1,s2,numFrames] = size(app.imageStack);
            if numFrames == 1
                app.meanStart.Value = 1;
                app.meanStart.Limits = [0,1];
                app.meanStart.Enable = 'off';
                app.meanStart.Editable = 'off';
                app.meanStop.Value = 1;
                app.meanStop.Limits = [0,1];
                app.meanStop.Enable = 'off';
                app.meanStop.Editable = 'off';
            else
                app.meanStart.Enable = 'on';
                app.meanStart.Editable = 'on';
                app.meanStop.Enable = 'on';
                app.meanStop.Editable = 'on';
                app.meanStart.Limits = [1,numFrames];
                app.meanStop.Limits = [1,numFrames];
                app.meanStart.Value = 1;
                if app.meanStop.Value > numFrames
                    app.meanStop.Value = numFrames;
                end
            end
            
            % set some default value;
            updateImageData(app);
            app.thresholdValue.Value = round(mean(app.imageMean,'all'));
            % binary check
            if ~app.thresholdValue.Value
                 app.thresholdValue.Value = 1;
            end
            updateHistogram(app);
            updateThreshold(app); 
            updateMask(app); 
            updateImagePreview(app);
            
        end
    end
    
    methods (Access = private)
        
        function app = updateThreshold(app)
            [s1,s2,s3] = size(app.imageStack); 
            
            app.thresholdPercent.UserData = 100 - (sum(sum(app.imageMean < app.thresholdValue.Value)) / (s1*s2)*100);
            app.thresholdPercent.Text = [num2str(round(app.thresholdPercent.UserData,2)),'%'];
            
            % plot
            children = get(app.imageHistogram,'Children');
            if size(children,1) == 2
                delete(children(1));
            end
            xline(app.imageHistogram, app.thresholdValue.Value,'-r', 'linewidth',2);
            set(app.imageHistogram, 'XLim', app.thresholdValue.Limits);
        end
        
        function app = updateMask(app)
            selectedButton = app.CreateMaskButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Manual'
                    app.imageMask = app.imageMean >= app.thresholdValue.Value;
                case 'Auto'
                    % need to be in gray scale
                    app.imageMask = imbinarize(mat2gray(app.imageMean),'global');
            end
            
        end
        
        function results = updateWindow(app)
            % check what should be displayed or not 
            selectedButton = app.CreateMaskButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Manual'
                    app.imageHistogram.Visible = 1;
                    app.ApplyThresholdLabel.Visible = 1;
                    app.thresholdValue.Visible = 1;
                    app.thresholdPercent.Visible = 1;
                    % update app
                    updateImageData(app);
                    app.thresholdValue.Value = mean(app.imageMean, 'all');
                    updateHistogram(app);
                    updateThreshold(app);
                    updateMask(app);
                    updateImagePreview(app);
                    
                case 'Auto'
                    cla(app.imageHistogram);
                    app.imageHistogram.Visible = 0;
                    app.ApplyThresholdLabel.Visible = 0;
                    app.thresholdValue.Visible = 0;
                    app.thresholdPercent.Visible = 0;
                    % update app
                    updateMask(app);
                    updateImagePreview(app);
            end

            
        end
    end
    

    methods (Access = private)

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.imageStack = []; % clear current image if loading new data 
            loadData(app);
        end

        % Button pushed function: UpdatePreviewButton
        function UpdatePreviewButtonPushed(app, event)
            selectedButton = app.CreateMaskButtonGroup.SelectedObject
            switch selectedButton.Text;
                case 'Manual'
                    updateImageData(app);
                    app.thresholdValue.Value = mean(app.imageMean, 'all');
                    updateHistogram(app);
                    updateThreshold(app);
                    updateMask(app);
                    updateImagePreview(app);

                case 'Auto'
                    updateMask(app);
                    updateImagePreview(app);
            end
        end

        % Value changed function: thresholdValue
        function thresholdValueValueChanged(app, event)
            updateThreshold(app);
            updateMask(app);
            updateImagePreview(app);
        end

        % Selection changed function: previewImageButton
        function previewImageButtonSelectionChanged(app, event)
            updateImagePreview(app); 
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if ~isempty(app.currentFile.UserData)
                [path, file] = fileparts(app.currentFile.UserData); 
                file = [file, '_mask']; 
                [file, path] = uiputfile('*.tif', 'File Selection', [path,'/',file]); 
                imwrite(uint16(app.imageMask), [path,file], 'Compression', 'none');
            end
        end

        % Menu selected function: ZAPMenu
        function ZAPMenuSelected(app, event)
            zap(); 
            delete(app);
        end

        % Selection changed function: CreateMaskButtonGroup
        function CreateMaskButtonGroupSelectionChanged(app, event)
            selectedButton = app.CreateMaskButtonGroup.SelectedObject;
            updateWindow(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaskMakerUIFigure = uifigure;
            app.MaskMakerUIFigure.Name = 'Mask Maker';
            app.FileMenu = uimenu(app.MaskMakerUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ZAPMenu = uimenu(app.FileMenu);
            app.ZAPMenu.MenuSelectedFcn = createCallbackFcn(app, @ZAPMenuSelected, true);
            app.ZAPMenu.Separator = 'on';
            app.ZAPMenu.Text = 'ZAP';
            app.imagePreview = uiaxes(app.MaskMakerUIFigure);
            app.imagePreview.XTick = [];
            app.imagePreview.YTick = [];
            app.imagePreview.Visible = 'off';
            app.thresholdValue = uispinner(app.MaskMakerUIFigure);
            app.thresholdValue.Step = 10;
            app.thresholdValue.Limits = [0 Inf];
            app.thresholdValue.RoundFractionalValues = 'on';
            app.thresholdValue.ValueChangedFcn = createCallbackFcn(app, @thresholdValueValueChanged, true);
            app.thresholdValue.Value = 1;
            app.thresholdPercent = uilabel(app.MaskMakerUIFigure);
            app.thresholdPercent.Text = '0%';
            app.ApplyThresholdLabel = uilabel(app.MaskMakerUIFigure);
            app.ApplyThresholdLabel.Text = 'Apply Threshold';
            app.imageHistogram = uiaxes(app.MaskMakerUIFigure);
            title(app.imageHistogram, 'Threshold')
            xlabel(app.imageHistogram, 'Intensity')
            ylabel(app.imageHistogram, 'Counts')
            app.ParametersPanel = uipanel(app.MaskMakerUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.AverageImagesPanel = uipanel(app.ParametersPanel);
            app.AverageImagesPanel.Title = 'Average Images:';
            app.StartLabel = uilabel(app.AverageImagesPanel);
            app.StartLabel.Text = 'Start:';
            app.meanStart = uieditfield(app.AverageImagesPanel, 'numeric');
            app.meanStart.Limits = [1 Inf];
            app.meanStart.Value = 1;
            app.StopLabel = uilabel(app.AverageImagesPanel);
            app.StopLabel.Text = 'Stop:';
            app.meanStop = uieditfield(app.AverageImagesPanel, 'numeric');
            app.meanStop.Limits = [1 Inf];
            app.meanStop.Value = 100;
            app.CreateMaskButtonGroup = uibuttongroup(app.ParametersPanel);
            app.CreateMaskButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CreateMaskButtonGroupSelectionChanged, true);
            app.CreateMaskButtonGroup.Title = 'Create Mask';
            app.ManualButton = uiradiobutton(app.CreateMaskButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutoButton = uiradiobutton(app.CreateMaskButtonGroup);
            app.AutoButton.Tooltip = {'Otsu Method'};
            app.AutoButton.Text = 'Auto';
            app.UpdatePreviewButton = uibutton(app.ParametersPanel, 'push');
            app.UpdatePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButtonPushed, true);
            app.UpdatePreviewButton.Text = 'Update Preview';
            app.SubtractBackground = uicheckbox(app.ParametersPanel);
            app.SubtractBackground.Text = 'Subtract Background';
            app.SubtractBackground.Value = true;
            app.previewImageButton = uibuttongroup(app.MaskMakerUIFigure);
            app.previewImageButton.SelectionChangedFcn = createCallbackFcn(app, @previewImageButtonSelectionChanged, true);
            app.MaskButton = uiradiobutton(app.previewImageButton);
            app.MaskButton.Text = 'Mask';
            app.MaskButton.Value = true;
            app.ImageButton = uiradiobutton(app.previewImageButton);
            app.ImageButton.Text = 'Image';
            app.ImageLabel = uilabel(app.MaskMakerUIFigure);
            app.ImageLabel.Text = 'Image:';
            app.currentFile = uieditfield(app.MaskMakerUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = maskMaker

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaskMakerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaskMakerUIFigure)
        end
    end
end