classdef projectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        appGUI                          matlab.ui.Figure
        ROIDetectionParametersPanel     matlab.ui.container.Panel
        MinSpacingpixelsEditFieldLabel  matlab.ui.control.Label
        minSpacingpixels                matlab.ui.control.NumericEditField
        MinAreapixels2EditFieldLabel    matlab.ui.control.Label
        minAreapixels2                  matlab.ui.control.NumericEditField
        gaussRefinementCheckBox         matlab.ui.control.CheckBox
        gaussSearchAreaText             matlab.ui.control.Label
        gaussSearchArea                 matlab.ui.control.NumericEditField
        ROIRadiuspixelsLabel            matlab.ui.control.Label
        roiRadius                       matlab.ui.control.NumericEditField
        gaussFitTolText                 matlab.ui.control.Label
        gaussFitTol                     matlab.ui.control.NumericEditField
        ImageAlignmentPanel             matlab.ui.container.Panel
        alignment1Button                matlab.ui.control.Button
        alignment2Button                matlab.ui.control.Button
        alignment3Button                matlab.ui.control.Button
        alignmentFiles1                 matlab.ui.control.TextArea
        alignmentFiles2                 matlab.ui.control.TextArea
        alignmentFiles3                 matlab.ui.control.TextArea
        ImageStacksPanel                matlab.ui.container.Panel
        imageStack2Button               matlab.ui.control.Button
        imageStack3Button               matlab.ui.control.Button
        imageStackFiles2                matlab.ui.control.TextArea
        imageStackFiles3                matlab.ui.control.TextArea
        imageStack1Button               matlab.ui.control.Button
        imageStackFiles1                matlab.ui.control.TextArea
        ImageMaskPanel                  matlab.ui.container.Panel
        loadMasksButton                 matlab.ui.control.Button
        maskFiles                       matlab.ui.control.TextArea
        maskChannelButtonGroup          matlab.ui.container.ButtonGroup
        maskChannel1                    matlab.ui.control.RadioButton
        maskChannel2                    matlab.ui.control.RadioButton
        maskChannel3                    matlab.ui.control.RadioButton
        createMask                      matlab.ui.control.CheckBox
        createMaskButtonGroup           matlab.ui.container.ButtonGroup
        maskAuto                        matlab.ui.control.RadioButton
        maskManual                      matlab.ui.control.RadioButton
        projectROIsButton               matlab.ui.control.Button
        changeFilePathButton            matlab.ui.control.Button
        filePath                        matlab.ui.control.TextArea
        ImagePropertiesPanel            matlab.ui.container.Panel
        FrameRateHzText                 matlab.ui.control.Label
        FrameRateHz                     matlab.ui.control.NumericEditField
        channelNameText1                matlab.ui.control.Label
        channelName1                    matlab.ui.control.EditField
        channelNameText2                matlab.ui.control.Label
        channelName2                    matlab.ui.control.EditField
        channelNameText3                matlab.ui.control.Label
        channelName3                    matlab.ui.control.EditField
        setChannelNameText              matlab.ui.control.Label
        performImageAlignment           matlab.ui.control.CheckBox
        NumerofChannelsDropDownLabel    matlab.ui.control.Label
        numChannels                     matlab.ui.control.DropDown
        alignButtonGroup                matlab.ui.container.ButtonGroup
        alignAuto                       matlab.ui.control.RadioButton
        alignManual                     matlab.ui.control.RadioButton
        currentFilePathText             matlab.ui.control.Label
        BackgroundSubtractionPanel      matlab.ui.container.Panel
        backgroundChannel1              matlab.ui.control.CheckBox
        backgroundChannel2              matlab.ui.control.CheckBox
        backgroundChannel3              matlab.ui.control.CheckBox
        applytoLabel                    matlab.ui.control.Label
        WhiteTopHatFilterLabel          matlab.ui.control.Label
        DiskRadiusLabel                 matlab.ui.control.Label
        diskRadius                      matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        alignmentPath1 % Full File Path for storing and output
        alignmentPath2
        alignmentPath3
        imageStacksPath1
        imageStacksPath2
        imageStacksPath3
        maskPath
    end
    
    methods (Access = public)
        
        % Select Files from Button Click
        function [files,path] = selectFiles(app)
            if isempty(app.filePath.Value{1})
                [files,path] = uigetfile('*.tif','Select one or more mask files',...
                    'Multiselect','on');
                if path
                    app.filePath.Value = cellstr(path);
                end
            else
                [files,path] = uigetfile('*.tif','Select one or more mask files',...
                    app.filePath.Value{1}, 'Multiselect','on');
            end
            % if no files selected, files == 0 with class double
            if isa(files,'double')
                files = char([]);
                path = char([]);
                % if only one file selectd, class(files) = char;
            elseif isa(files,'char')
                files = cellstr(files);
            end
            
        end
        
        function imageData = runProjectROIs(app)
            % convert objects into data stucture for projectROIs
            % should eventually do this as in OOP but for now...
            imageData = struct;
            
            numStacks = countNumStacks(app);
            numStacks = numStacks(numStacks~=0);
            if length(unique(numStacks)) ~= 1
                errordlg('Number of files per channel do not match','Error');
                return
            end
            
            % Image Properties Panel
            imageData.info.numStacks = unique(numStacks);
            imageData.info.numChannels = str2num(app.numChannels.Value);
            imageData.info.frameRateHz = app.FrameRateHz.Value;
            imageData.info.channelNames = cell(3,1);
            imageData.info.channelNames{1} = app.channelName1.Value;
            imageData.info.channelNames{2} = app.channelName2.Value;
            imageData.info.channelNames{3} = app.channelName3.Value;
            
            % ROI Detection Parameters Panel
            imageData.roiParameters.minSpacing = app.minSpacingpixels.Value;
            imageData.roiParameters.minArea = app.minAreapixels2.Value;
            imageData.roiParameters.roiRadius = app.roiRadius.Value;
            imageData.roiParameters.gaussRefine = app.gaussRefinementCheckBox.Value;
            if imageData.roiParameters.gaussRefine
                imageData.roiParameters.gaussRefineArea = app.gaussSearchArea.Value;
                imageData.roiParameters. gaussFitTol = app.gaussFitTol.Value;
            else
                imageData.roiParameters.gaussRefineArea = 0;
                imageData.roiParameters. gaussFitTol = 0;
            end
            
            % Image Stacks
            imageData.stacks.path = cell(3,1);
            imageData.stacks.path{1,1} = app.imageStacksPath1;
            imageData.stacks.path{2,1} = app.imageStacksPath2;
            imageData.stacks.path{3,1} = app.imageStacksPath3;
            imageData.stacks.files = cell(3,1);
            imageData.stacks.files{1,1} = app.imageStackFiles1.Value;
            imageData.stacks.files{2,1} = app.imageStackFiles2.Value;
            imageData.stacks.files{3,1} = app.imageStackFiles3.Value;
            
            % Image Mask
            imageData.masks.createMask = app.createMask.Value;
            if imageData.masks.createMask
                if app.maskAuto.Value == 1
                    imageData.masks.method = 'auto';
                elseif app.maskManual.Value == 1
                    imageData.masks.method = 'manual';
                end
            else
                imageData.masks.method = []; 
            end
            imageData.masks.path = app.maskPath;
            imageData.masks.files = app.maskFiles.Value;
            if app.maskChannel1.Value;
                imageData.masks.maskChannel = 1;
            elseif app.maskChannel2.Value
                imageData.masks.maskChannel = 2;
            elseif app.maskChannel3.Value
                imageData.masks.maskChannel = 3;
            end
            
            % Alignment Files Panel
            imageData.align.alignImages = app.performImageAlignment.Value;
            if imageData.align.alignImages
                if app.alignAuto.Value == 1
                    imageData.align.method = 'auto';
                elseif app.alignManual.Value == 1
                    imageData.align.method = 'manual';
                end
            else
                imageData.align.method = [];  
            end
            imageData.align.path = cell(3,1);
            imageData.align.path{1,1} = app.alignmentPath1;
            imageData.align.path{2,1} = app.alignmentPath2;
            imageData.align.path{3,1} = app.alignmentPath3;
            imageData.align.files = cell(3,1);
            imageData.align.files{1,1} = app.alignmentFiles1.Value;
            imageData.align.files{2,1} = app.alignmentFiles2.Value;
            imageData.align.files{3,1} = app.alignmentFiles3.Value;
            
            % Background Subtraction (filtering)
            imageData.background.applyToChannels = [0,0,0];
            imageData.background.applyToChannels(1) = app.backgroundChannel1.Value;
            imageData.background.applyToChannels(2) = app.backgroundChannel2.Value;
            imageData.background.applyToChannels(3) = app.backgroundChannel3.Value;
            imageData.background.diskRadius = app.diskRadius.Value;
            % need to edit still to grab necessary input values..
            
            % run with external function
            % data = projectROIs(imageData);
            
            % save rois in file path
            
            [saveFile,savePath] = uiputfile('*.mat','Save Image Data',app.filePath.Value{1});
            save([savePath,saveFile], 'imageData');
            
            disp('done')
            
        end
    end
    
    methods (Access = private)
        
        function numImages = countNumStacks(app)
            % long work around to count the number of loaded images per channel
            numImages = zeros(7,1); % 3 stacks, 3 alignment, 1 mask
            if str2num(app.numChannels.Value) > 0
                if app.imageStackFiles1.Enable
                    if isempty(app.imageStackFiles1.Value{1})
                        numImages(1)=-1;
                    else
                        numImages(1) = length(app.imageStackFiles1.Value);
                    end
                end
                if app.performImageAlignment.Value
                    if isempty(app.alignmentFiles1.Value{1})
                        numImages(4)=-1;
                    else
                        numImages(4) = length(app.alignmentFiles1.Value);
                    end
                end
            end
            % numChannels > 1
            if str2num(app.numChannels.Value) > 1
                if app.imageStackFiles2.Enable
                    if isempty(app.imageStackFiles2.Value{1})
                        numImages(2)=-1;
                    else
                        numImages(2) = length(app.imageStackFiles2.Value);
                    end
                end
                if app.performImageAlignment.Value
                    if isempty(app.alignmentFiles2.Value{1})
                        numImages(5)=-1;
                    else
                        numImages(5) = length(app.alignmentFiles2.Value);
                    end
                end
            end
            % numChannels > 2
            if str2num(app.numChannels.Value) > 2
                if app.imageStackFiles3.Enable
                    if isempty(app.imageStackFiles3.Value{1})
                        numImages(3)=-1;
                    else
                        numImages(3) = length(app.imageStackFiles3.Value);
                    end
                end
                if app.performImageAlignment.Value
                    if isempty(app.alignmentFiles3.Value{1})
                        numImages(6)=-1;
                    else
                        numImages(6) = length(app.alignmentFiles2.Value);
                    end
                end
            end
            % mask files
            if ~app.createMask.Value
                if isempty(app.maskFiles.Value{1})
                    numImages(7) = -1;
                else
                    numImages(7) = length(app.maskFiles.Value);
                end
            end
        end
        
        function noErrors = runErrorCheck(app)
            % find possible errors and let user know prior to executing projectROIs
            noErrors = 1;
            
            % nothing selected but projectROIs pressed
        end
    end
    

    methods (Access = private)

        % Button pushed function: alignment1Button
        function load_alignment1(app, event)
            [app.alignmentFiles1.Value,app.alignmentPath1] = selectFiles(app);
        end

        % Button pushed function: changeFilePathButton
        function button_selectFilePath(app, event)
            path = uigetdir;
            if path
                app.filePath.Value = uigetdir;
            end
        end

        % Button pushed function: alignment2Button
        function load_alignment2(app, event)
            [app.alignmentFiles2.Value,app.alignmentPath2] = selectFiles(app);
        end

        % Button pushed function: alignment3Button
        function load_alignment3(app, event)
            [app.alignmentFiles3.Value,app.alignmentPath3] = selectFiles(app);
        end

        % Button pushed function: imageStack1Button
        function load_imageStack1(app, event)
            [app.imageStackFiles1.Value,app.imageStacksPath1] = selectFiles(app);
        end

        % Button pushed function: imageStack2Button
        function load_imageStack2(app, event)
            [app.imageStackFiles2.Value,app.imageStacksPath2] = selectFiles(app);
        end

        % Button pushed function: imageStack3Button
        function load_imageStack3(app, event)
            [app.imageStackFiles3.Value,app.imageStacksPath3] = selectFiles(app);
        end

        % Button pushed function: projectROIsButton
        function button_projectROIs(app, event)
            errorCheck = runErrorCheck(app);
            if errorCheck
                imageData = runProjectROIs(app);
            end
            % close the app once ROIs have been projected
            % delete(app);
        end

        % Value changed function: numChannels
        function callback_numChannels(app, event)
            value = app.numChannels.Value;
            if str2num(value) == 0
                % image stacks
                app.imageStack1Button.Enable = 'off';
                app.imageStackFiles1.Enable = 'off';
                app.imageStackFiles1.Editable = 'off';
                app.imageStack2Button.Enable = 'off';
                app.imageStackFiles2.Enable = 'off';
                app.imageStackFiles2.Editable = 'off';
                app.imageStack3Button.Enable = 'off';
                app.imageStackFiles3.Enable = 'off';
                app.imageStackFiles3.Editable = 'off';
                % alignment files
                if app.performImageAlignment.Value
                    app.alignment1Button.Enable = 'off';
                    app.alignmentFiles1.Enable = 'off';
                    app.alignmentFiles1.Editable = 'off';
                    app.alignment2Button.Enable = 'off';
                    app.alignmentFiles2.Enable = 'off';
                    app.alignmentFiles2.Editable = 'off';
                    app.alignment3Button.Enable = 'off';
                    app.alignmentFiles3.Enable = 'off';
                    app.alignmentFiles3.Editable = 'off';
                end
                % misc. options
                app.FrameRateHz.Enable = 'off';
                app.FrameRateHz.Editable = 'off';
                app.FrameRateHzText.Enable = 'off';
                app.performImageAlignment.Value = 0;
                app.performImageAlignment.Enable = 'off';
                
                app.alignAuto.Enable = 'off';
                app.alignManual.Enable = 'off';
                app.gaussRefinementCheckBox.Value = 0;
                app.gaussRefinementCheckBox.Enable = 'off';
                app.gaussSearchArea.Enable = 'off';
                app.gaussSearchArea.Editable = 'off';
                app.gaussSearchAreaText.Enable  = 'off';
                
                pp.alignAuto.Enable = 'off'; 
                app.alignManual.Enable = 'off'; 
                app.gaussFitTol.Enable = 'off';
                app.gaussFitTol.Editable = 'off';
                app.gaussFitTolText.Enable  = 'off';
                app.createMask.Enable = 'off'; 
                app.createMask.Value = 0; 
                app.maskAuto.Enable = 'off'; 
                app.maskManual.Enable = 'off';
                
                app.loadMasksButton.Enable = 'on';
                app.maskFiles.Enable = 'on';
                app.maskFiles.Editable = 'on';
                app.maskChannel1.Enable = 'off';
                app.maskChannel2.Enable = 'off';
                app.maskChannel3.Enable = 'off';
                app.maskChannel1.Value = 0;
                app.maskChannel2.Value = 0;
                app.maskChannel3.Value = 0;
                
                app.setChannelNameText.Enable = 'off';
                app.channelName1.Editable = 'off';
                app.channelName1.Enable = 'off';
                app.channelNameText1.Enable = 'off';
                app.channelName2.Editable = 'off';
                app.channelName2.Enable = 'off';
                app.channelNameText2.Enable = 'off';
                app.channelName3.Editable = 'off';
                app.channelName3.Enable = 'off';
                app.channelNameText3.Enable = 'off';
                app.backgroundChannel1.Enable = 'off';
                app.backgroundChannel2.Enable = 'off';
                app.backgroundChannel3.Enable = 'off';
                app.backgroundChannel1.Value = 0;
                app.backgroundChannel2.Value = 0;
                app.backgroundChannel3.Value = 0;
            elseif str2num(value) == 1
                % image stacks
                app.imageStack1Button.Enable = 'on';
                app.imageStackFiles1.Enable = 'on';
                app.imageStackFiles1.Editable = 'on';
                app.imageStack2Button.Enable = 'off';
                app.imageStackFiles2.Enable = 'off';
                app.imageStackFiles2.Editable = 'off';
                app.imageStack3Button.Enable = 'off';
                app.imageStackFiles3.Enable = 'off';
                app.imageStackFiles3.Editable = 'off';
                % alignment files
                if app.performImageAlignment.Value
                    app.alignment1Button.Enable = 'on';
                    app.alignmentFiles1.Enable = 'on';
                    app.alignmentFiles1.Editable = 'on';
                    app.alignment2Button.Enable = 'off';
                    app.alignmentFiles2.Enable = 'off';
                    app.alignmentFiles2.Editable = 'off';
                    app.alignment3Button.Enable = 'off';
                    app.alignmentFiles3.Enable = 'off';
                    app.alignmentFiles3.Editable = 'off';
                end
                % misc. options
                
                app.alignAuto.Enable = 'on'; 
                app.alignManual.Enable = 'on'; 
                app.gaussFitTol.Enable = 'on';
                app.gaussFitTol.Editable = 'on';
                app.gaussFitTolText.Enable  = 'on';
                app.createMask.Enable = 'on'; 
                app.maskAuto.Enable = 'on'; 
                app.maskManual.Enable = 'on';
                
                app.FrameRateHz.Enable = 'on';
                app.FrameRateHz.Editable = 'on';
                app.FrameRateHzText.Enable = 'on';
                app.performImageAlignment.Enable = 'on';
                app.gaussRefinementCheckBox.Enable = 'on';
                app.setChannelNameText.Enable = 'on';
                
                app.maskChannel1.Enable = 'on';
                app.maskChannel2.Enable = 'off';
                app.maskChannel3.Enable = 'off';
                app.maskChannel1.Value = 1;
                app.maskChannel2.Value = 0;
                app.maskChannel3.Value = 0;
                app.channelName1.Editable = 'on';
                app.channelName1.Enable = 'on';
                app.channelNameText1.Enable = 'on';
                app.channelName2.Editable = 'off';
                app.channelName2.Enable = 'off';
                app.channelNameText2.Enable = 'off';
                app.channelName3.Editable = 'off';
                app.channelName3.Enable = 'off';
                app.channelNameText3.Enable = 'off';
                % currently disable background substraction
                app.backgroundChannel1.Enable = 'off';
                app.backgroundChannel2.Enable = 'off';
                app.backgroundChannel3.Enable = 'off';
                app.backgroundChannel2.Value = 0;
                app.backgroundChannel2.Value = 0;
                app.backgroundChannel3.Value = 0;
            elseif str2num(value) == 2
                % image stacks
                app.imageStack1Button.Enable = 'on';
                app.imageStackFiles1.Enable = 'on';
                app.imageStackFiles1.Editable = 'on';
                app.imageStack2Button.Enable = 'on';
                app.imageStackFiles2.Enable = 'on';
                app.imageStackFiles2.Editable = 'on';
                app.imageStack3Button.Enable = 'off';
                app.imageStackFiles3.Enable = 'off';
                app.imageStackFiles3.Editable = 'off';
                % alignment files
                if app.performImageAlignment.Value
                    app.alignment1Button.Enable = 'on';
                    app.alignmentFiles1.Enable = 'on';
                    app.alignmentFiles1.Editable = 'on';
                    app.alignment2Button.Enable = 'on';
                    app.alignmentFiles2.Enable = 'on';
                    app.alignmentFiles2.Editable = 'on';
                    app.alignment3Button.Enable = 'off';
                    app.alignmentFiles3.Enable = 'off';
                    app.alignmentFiles3.Editable = 'off';
                end
                % misc. options
                app.alignAuto.Enable = 'on'; 
                app.alignManual.Enable = 'on'; 
                app.gaussFitTol.Enable = 'on';
                app.gaussFitTol.Editable = 'on';
                app.gaussFitTolText.Enable  = 'on';
                app.createMask.Enable = 'on'; 
                app.maskAuto.Enable = 'on'; 
                app.maskManual.Enable = 'on';
                
                app.FrameRateHz.Enable = 'on';
                app.FrameRateHz.Editable = 'on';
                app.FrameRateHzText.Enable = 'on';
                app.performImageAlignment.Enable = 'on';
                app.gaussRefinementCheckBox.Enable = 'on';
                app.setChannelNameText.Enable = 'on';
                app.maskChannel1.Enable = 'on';
                app.maskChannel2.Enable = 'on';
                app.maskChannel3.Enable = 'off';
                app.channelName1.Editable = 'on';
                app.channelName1.Enable = 'on';
                app.channelNameText1.Enable = 'on';
                app.channelName2.Editable = 'on';
                app.channelName2.Enable = 'on';
                app.channelNameText2.Enable = 'on';
                app.channelName3.Editable = 'off';
                app.channelName3.Enable = 'off';
                app.channelNameText3.Enable = 'off';
                
                app.backgroundChannel1.Enable = 'off';
                app.backgroundChannel2.Enable = 'off';
                app.backgroundChannel3.Enable = 'off';
                app.backgroundChannel1.Value = 0;
                app.backgroundChannel2.Value = 0;
                app.backgroundChannel3.Value = 0;
                
                %app.backgroundChannel1.Enable = 'on';
                %app.backgroundChannel2.Enable = 'on';
                %app.backgroundChannel3.Enable = 'off';
                %app.backgroundChannel3.Value = 0;
            elseif str2num(value) == 3
                % image stacks
                app.imageStack1Button.Enable = 'on';
                app.imageStackFiles1.Enable = 'on';
                app.imageStackFiles1.Editable = 'on';
                app.imageStack2Button.Enable = 'on';
                app.imageStackFiles2.Enable = 'on';
                app.imageStackFiles2.Editable = 'on';
                app.imageStack3Button.Enable = 'on';
                app.imageStackFiles3.Enable = 'on';
                app.imageStackFiles3.Editable = 'on';
                % alignment files
                if app.performImageAlignment.Value
                    app.alignment1Button.Enable = 'on';
                    app.alignmentFiles1.Enable = 'on';
                    app.alignmentFiles1.Editable = 'on';
                    app.alignment2Button.Enable = 'on';
                    app.alignmentFiles2.Enable = 'on';
                    app.alignmentFiles2.Editable = 'on';
                    app.alignment3Button.Enable = 'on';
                    app.alignmentFiles3.Enable = 'on';
                    app.alignmentFiles3.Editable = 'on';
                end
                
                % misc. options
                app.alignAuto.Enable = 'on'; 
                app.alignManual.Enable = 'on'; 
                app.gaussFitTol.Enable = 'on';
                app.gaussFitTol.Editable = 'on';
                app.gaussFitTolText.Enable  = 'on';
                app.createMask.Enable = 'on'; 
                app.maskAuto.Enable = 'on'; 
                app.maskManual.Enable = 'on';
                
                app.FrameRateHz.Enable = 'on';
                app.FrameRateHz.Editable = 'on';
                app.FrameRateHzText.Enable = 'on';
                app.performImageAlignment.Enable = 'on';
                app.gaussRefinementCheckBox.Enable = 'on';
                app.setChannelNameText.Enable = 'on';
                app.maskChannel1.Enable = 'on';
                app.maskChannel2.Enable = 'on';
                app.maskChannel3.Enable = 'on';
                app.channelName1.Editable = 'on';
                app.channelName1.Enable = 'on';
                app.channelNameText1.Enable = 'on';
                app.channelName2.Editable = 'on';
                app.channelName2.Enable = 'on';
                app.channelNameText2.Enable = 'on';
                app.channelName3.Editable = 'on';
                app.channelName3.Enable = 'on';
                app.channelNameText3.Enable = 'on';
                
                app.backgroundChannel1.Enable = 'off';
                app.backgroundChannel2.Enable = 'off';
                app.backgroundChannel3.Enable = 'off';
                app.backgroundChannel1.Value = 0;
                app.backgroundChannel2.Value = 0;
                app.backgroundChannel3.Value = 0;
                
                %app.backgroundChannel1.Enable = 'on';
                %app.backgroundChannel2.Enable = 'on';
                %app.backgroundChannel3.Enable = 'on';
            end
        end

        % Value changed function: createMask
        function callback_createMask(app, event)
            value = app.createMask.Value;
            if value
                app.maskAuto.Enable = 'on';
                app.maskManual.Enable = 'on';
                app.loadMasksButton.Enable = 0;
                app.maskFiles.Enable = 0;
                app.maskFiles.Editable = 0;
                app.maskFiles.Value = char([]);
            else
                app.maskAuto.Enable = 'off';
                app.maskManual.Enable = 'off';
                app.loadMasksButton.Enable = 1;
                app.maskFiles.Enable = 1;
                app.maskFiles.Editable = 1;
            end
        end

        % Value changed function: gaussRefinementCheckBox
        function callback_gaussRefine(app, event)
            value = app.gaussRefinementCheckBox.Value;
            if value
                app.gaussSearchArea.Editable = 1;
                app.gaussSearchArea.Enable = 1;
                app.gaussSearchAreaText.Enable = 1;
                app.gaussFitTol.Enable = 1;
                app.gaussFitTol.Editable = 1;
                app.gaussFitTolText.Enable = 1;
            else
                app.gaussSearchArea.Editable = 0;
                app.gaussSearchArea.Enable = 0;
                app.gaussSearchAreaText.Enable = 0;
                app.gaussFitTol.Enable = 0;
                app.gaussFitTol.Editable = 0;
                app.gaussFitTolText.Enable = 0;
            end
        end

        % Value changed function: performImageAlignment
        function callback_performImageAlignment(app, event)
            value = app.performImageAlignment.Value;
            if ~value % turn off all
                app.alignAuto.Enable = 'off';
                app.alignManual.Enable = 'off';
                app.alignment1Button.Enable = 'off';
                app.alignmentFiles1.Enable = 'off';
                app.alignmentFiles1.Editable = 'off';
                app.alignmentFiles1.Value = char([]);
                
                app.alignment2Button.Enable='off';
                app.alignmentFiles2.Enable = 'off';
                app.alignmentFiles2.Editable = 'off';
                app.alignmentFiles2.Value = char([]);
                
                app.alignment3Button.Enable='off';
                app.alignmentFiles3.Enable = 'off';
                app.alignmentFiles3.Editable = 'off';
                app.alignmentFiles3.Value = char([]);
            else
                app.alignAuto.Enable = 'on';
                app.alignManual.Enable = 'on';
                app.alignment1Button.Enable = 'on';
                app.alignmentFiles1.Enable = 'on';
                app.alignmentFiles1.Editable = 'on';
                if str2num(app.numChannels.Value) > 1
                    app.alignment2Button.Enable = 'on';
                    app.alignmentFiles2.Enable = 'on';
                    app.alignmentFiles2.Editable = 'on';
                end
                if str2num(app.numChannels.Value) > 2
                    app.alignment3Button.Enable = 'on';
                    app.alignmentFiles3.Enable = 'on';
                    app.alignmentFiles3.Editable = 'on';
                end
            end
        end

        % Value changed function: channelName1
        function callback_nameChannel1(app, event)
    value = app.channelName1.Value;
        end

        % Value changed function: channelName2
        function callback_nameChannel2(app, event)

        end

        % Value changed function: channelName3
        function callback_nameChannel3(app, event)
    value = app.channelName3.Value;
        end

        % Button pushed function: loadMasksButton
        function load_imageMask(app, event)
            [app.maskFiles.Value,app.maskPath] = selectFiles(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.appGUI = uifigure;
            app.appGUI.Name = 'ROI Projection';
            app.ROIDetectionParametersPanel = uipanel(app.appGUI);
            app.ROIDetectionParametersPanel.Title = 'ROI Detection Parameters';
            app.MinSpacingpixelsEditFieldLabel = uilabel(app.ROIDetectionParametersPanel);
            app.MinSpacingpixelsEditFieldLabel.Text = 'Min Spacing (pixels)';
            app.minSpacingpixels = uieditfield(app.ROIDetectionParametersPanel, 'numeric');
            app.minSpacingpixels.Value = 5;
            app.MinAreapixels2EditFieldLabel = uilabel(app.ROIDetectionParametersPanel);
            app.MinAreapixels2EditFieldLabel.Text = 'Min Area (pixels^2)';
            app.minAreapixels2 = uieditfield(app.ROIDetectionParametersPanel, 'numeric');
            app.minAreapixels2.Limits = [0 100];
            app.minAreapixels2.Value = 2;
            app.gaussRefinementCheckBox = uicheckbox(app.ROIDetectionParametersPanel);
            app.gaussRefinementCheckBox.ValueChangedFcn = createCallbackFcn(app, @callback_gaussRefine, true);
            app.gaussRefinementCheckBox.Text = 'Gaussian Refinement';
            app.gaussRefinementCheckBox.Value = true;
            app.gaussSearchAreaText = uilabel(app.ROIDetectionParametersPanel);
            app.gaussSearchAreaText.Text = 'Search Area (pixels)';
            app.gaussSearchArea = uieditfield(app.ROIDetectionParametersPanel, 'numeric');
            app.gaussSearchArea.Limits = [1 100];
            app.gaussSearchArea.Value = 16;
            app.ROIRadiuspixelsLabel = uilabel(app.ROIDetectionParametersPanel);
            app.ROIRadiuspixelsLabel.Text = 'ROI Radius (pixels)';
            app.roiRadius = uieditfield(app.ROIDetectionParametersPanel, 'numeric');
            app.roiRadius.Limits = [0 100];
            app.roiRadius.RoundFractionalValues = 'on';
            app.roiRadius.Value = 3;
            app.gaussFitTolText = uilabel(app.ROIDetectionParametersPanel);
            app.gaussFitTolText.Text = 'Fit Tolerance';
            app.gaussFitTol = uieditfield(app.ROIDetectionParametersPanel, 'numeric');
            app.gaussFitTol.Limits = [1e-100 1];
            app.gaussFitTol.Value = 1e-06;
            app.ImageAlignmentPanel = uipanel(app.appGUI);
            app.ImageAlignmentPanel.Title = 'Image Alignment';
            app.alignment1Button = uibutton(app.ImageAlignmentPanel, 'push');
            app.alignment1Button.ButtonPushedFcn = createCallbackFcn(app, @load_alignment1, true);
            app.alignment1Button.Text = 'Alignment 1';
            app.alignment2Button = uibutton(app.ImageAlignmentPanel, 'push');
            app.alignment2Button.ButtonPushedFcn = createCallbackFcn(app, @load_alignment2, true);
            app.alignment2Button.Text = {'Alignment 2'; ''};
            app.alignment3Button = uibutton(app.ImageAlignmentPanel, 'push');
            app.alignment3Button.ButtonPushedFcn = createCallbackFcn(app, @load_alignment3, true);
            app.alignment3Button.Enable = 'off';
            app.alignment3Button.Text = {'Alignment 3'; ''};
            app.alignmentFiles1 = uitextarea(app.ImageAlignmentPanel);
            app.alignmentFiles2 = uitextarea(app.ImageAlignmentPanel);
            app.alignmentFiles3 = uitextarea(app.ImageAlignmentPanel);
            app.alignmentFiles3.Editable = 'off';
            app.alignmentFiles3.Enable = 'off';
            app.ImageStacksPanel = uipanel(app.appGUI);
            app.ImageStacksPanel.Title = 'Image Stacks ';
            app.imageStack2Button = uibutton(app.ImageStacksPanel, 'push');
            app.imageStack2Button.ButtonPushedFcn = createCallbackFcn(app, @load_imageStack2, true);
            app.imageStack2Button.Text = 'Image Stack 2';
            app.imageStack3Button = uibutton(app.ImageStacksPanel, 'push');
            app.imageStack3Button.ButtonPushedFcn = createCallbackFcn(app, @load_imageStack3, true);
            app.imageStack3Button.Enable = 'off';
            app.imageStack3Button.Text = 'Image Stack 3';
            app.imageStackFiles2 = uitextarea(app.ImageStacksPanel);
            app.imageStackFiles3 = uitextarea(app.ImageStacksPanel);
            app.imageStackFiles3.Editable = 'off';
            app.imageStackFiles3.Enable = 'off';
            app.imageStack1Button = uibutton(app.ImageStacksPanel, 'push');
            app.imageStack1Button.ButtonPushedFcn = createCallbackFcn(app, @load_imageStack1, true);
            app.imageStack1Button.Text = 'Image Stack 1';
            app.imageStackFiles1 = uitextarea(app.ImageStacksPanel);
            app.ImageMaskPanel = uipanel(app.appGUI);
            app.ImageMaskPanel.Title = 'Image Mask';
            app.loadMasksButton = uibutton(app.ImageMaskPanel, 'push');
            app.loadMasksButton.ButtonPushedFcn = createCallbackFcn(app, @load_imageMask, true);
            app.loadMasksButton.Enable = 'off';
            app.loadMasksButton.Text = {'Load Mask(s)'; ''};
            app.maskFiles = uitextarea(app.ImageMaskPanel);
            app.maskFiles.Editable = 'off';
            app.maskFiles.Enable = 'off';
            app.maskChannelButtonGroup = uibuttongroup(app.ImageMaskPanel);
            app.maskChannelButtonGroup.Title = 'Mask Channel';
            app.maskChannel1 = uiradiobutton(app.maskChannelButtonGroup);
            app.maskChannel1.Text = {'Channel 1'; ''};
            app.maskChannel1.Value = true;
            app.maskChannel2 = uiradiobutton(app.maskChannelButtonGroup);
            app.maskChannel2.Text = 'Channel 2';
            app.maskChannel3 = uiradiobutton(app.maskChannelButtonGroup);
            app.maskChannel3.Enable = 'off';
            app.maskChannel3.Text = {'Channel 3'; ''};
            app.createMask = uicheckbox(app.ImageMaskPanel);
            app.createMask.ValueChangedFcn = createCallbackFcn(app, @callback_createMask, true);
            app.createMask.Text = 'Create Mask';
            app.createMask.Value = true;
            app.createMaskButtonGroup = uibuttongroup(app.ImageMaskPanel);
            app.maskAuto = uiradiobutton(app.createMaskButtonGroup);
            app.maskAuto.Text = 'Auto';
            app.maskAuto.Value = true;
            app.maskManual = uiradiobutton(app.createMaskButtonGroup);
            app.maskManual.Text = 'Manual';
            app.projectROIsButton = uibutton(app.appGUI, 'push');
            app.projectROIsButton.ButtonPushedFcn = createCallbackFcn(app, @button_projectROIs, true);
            app.projectROIsButton.Text = 'Project ROIs';
            app.changeFilePathButton = uibutton(app.appGUI, 'push');
            app.changeFilePathButton.ButtonPushedFcn = createCallbackFcn(app, @button_selectFilePath, true);
            app.changeFilePathButton.Text = 'Change File Path';
            app.filePath = uitextarea(app.appGUI);
            app.ImagePropertiesPanel = uipanel(app.appGUI);
            app.ImagePropertiesPanel.Title = 'Image Properties';
            app.FrameRateHzText = uilabel(app.ImagePropertiesPanel);
            app.FrameRateHzText.Text = 'Frame Rate (Hz)';
            app.FrameRateHz = uieditfield(app.ImagePropertiesPanel, 'numeric');
            app.FrameRateHz.Limits = [0 Inf];
            app.FrameRateHz.Value = 10;
            app.channelNameText1 = uilabel(app.ImagePropertiesPanel);
            app.channelNameText1.Text = '1';
            app.channelName1 = uieditfield(app.ImagePropertiesPanel, 'text');
            app.channelName1.ValueChangedFcn = createCallbackFcn(app, @callback_nameChannel1, true);
            app.channelName1.Value = 'Channel 1';
            app.channelNameText2 = uilabel(app.ImagePropertiesPanel);
            app.channelNameText2.Text = '2';
            app.channelName2 = uieditfield(app.ImagePropertiesPanel, 'text');
            app.channelName2.ValueChangedFcn = createCallbackFcn(app, @callback_nameChannel2, true);
            app.channelName2.Value = 'Channel 2';
            app.channelNameText3 = uilabel(app.ImagePropertiesPanel);
            app.channelNameText3.Enable = 'off';
            app.channelNameText3.Text = '3';
            app.channelName3 = uieditfield(app.ImagePropertiesPanel, 'text');
            app.channelName3.ValueChangedFcn = createCallbackFcn(app, @callback_nameChannel3, true);
            app.channelName3.Editable = 'off';
            app.channelName3.Enable = 'off';
            app.channelName3.Value = 'Channel 3';
            app.setChannelNameText = uilabel(app.ImagePropertiesPanel);
            app.setChannelNameText.Text = 'Set Channel Name';
            app.performImageAlignment = uicheckbox(app.ImagePropertiesPanel);
            app.performImageAlignment.ValueChangedFcn = createCallbackFcn(app, @callback_performImageAlignment, true);
            app.performImageAlignment.Text = 'Perform Image Alignment';
            app.performImageAlignment.Value = true;
            app.NumerofChannelsDropDownLabel = uilabel(app.ImagePropertiesPanel);
            app.NumerofChannelsDropDownLabel.Text = 'Numer of Channels';
            app.numChannels = uidropdown(app.ImagePropertiesPanel);
            app.numChannels.Items = {'0', '1', '2', '3'};
            app.numChannels.ValueChangedFcn = createCallbackFcn(app, @callback_numChannels, true);
            app.numChannels.Value = '2';
            app.alignButtonGroup = uibuttongroup(app.ImagePropertiesPanel);
            app.alignAuto = uiradiobutton(app.alignButtonGroup);
            app.alignAuto.Text = 'Auto';
            app.alignAuto.Value = true;
            app.alignManual = uiradiobutton(app.alignButtonGroup);
            app.alignManual.Text = 'Manual';
            app.currentFilePathText = uilabel(app.appGUI);
            app.currentFilePathText.Text = 'Current File Path: ';
            app.BackgroundSubtractionPanel = uipanel(app.appGUI);
            app.BackgroundSubtractionPanel.Title = 'Background Subtraction';
            app.backgroundChannel1 = uicheckbox(app.BackgroundSubtractionPanel);
            app.backgroundChannel1.Enable = 'off';
            app.backgroundChannel1.Text = 'Channel 1';
            app.backgroundChannel2 = uicheckbox(app.BackgroundSubtractionPanel);
            app.backgroundChannel2.Enable = 'off';
            app.backgroundChannel2.Text = 'Channel 2';
            app.backgroundChannel3 = uicheckbox(app.BackgroundSubtractionPanel);
            app.backgroundChannel3.Enable = 'off';
            app.backgroundChannel3.Text = 'Channel 3';
            app.applytoLabel = uilabel(app.BackgroundSubtractionPanel);
            app.applytoLabel.Text = 'Apply to: ';
            app.WhiteTopHatFilterLabel = uilabel(app.BackgroundSubtractionPanel);
            app.WhiteTopHatFilterLabel.Text = 'White Top Hat Filter';
            app.DiskRadiusLabel = uilabel(app.BackgroundSubtractionPanel);
            app.DiskRadiusLabel.Enable = 'off';
            app.DiskRadiusLabel.Text = 'Disk Radius';
            app.diskRadius = uieditfield(app.BackgroundSubtractionPanel, 'numeric');
            app.diskRadius.Limits = [1 10];
            app.diskRadius.RoundFractionalValues = 'on';
            app.diskRadius.Enable = 'off';
            app.diskRadius.Value = 3;
        end
    end

    methods (Access = public)

        % Construct app
        function app = projectionGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.appGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.appGUI)
        end
    end
end