classdef testmask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        imageHistogram              matlab.ui.control.UIAxes
        imageMaskPreview            matlab.ui.control.UIAxes
        Button                      matlab.ui.control.Button
        imageFilePath               matlab.ui.control.EditField
        LoadImageStackButton        matlab.ui.control.Button
        thresholdvalueSpinnerLabel  matlab.ui.control.Label
        thresholdvalueSpinner       matlab.ui.control.Spinner
        thresholdPercent            matlab.ui.control.Label
        SaveImageMaskButton         matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        AverageFramesButton         matlab.ui.control.StateButton
        StartEditFieldLabel         matlab.ui.control.Label
        StartEditField              matlab.ui.control.NumericEditField
        StopEditFieldLabel          matlab.ui.control.Label
        SubtractBackgroundCheckBox  matlab.ui.control.CheckBox
        StopEditField               matlab.ui.control.NumericEditField
        ResetImageButton            matlab.ui.control.Button
        UpateImageButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        originalImage   % intital loaded image. do not modify w/ callback
        currentImage    % update w/ each callback
        maskImage       % binary form of currentImage at user defined threshold
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        
        function app = updatePreview(app)
            x1 = app.StartEditField.Value;
            x2 = app.StopEditField.Value;
            
            currentImage = uint16(mean(app.originalImage(:,:,x1:x2),3));
            
            if app.SubtractBackgroundCheckBox.Value
                se = strel('disk',3);
                currentImage = imtophat(currentImage,se);
            end
            
            app.currentImage = currentImage; 
            imshow(app.currentImage, [], 'parent', app.imageMaskPreview); 
            
            % udate histrogram axis
            updateHistogram(app);
            
        end
        
        function app = updateHistogram(app)
            x1 = double(floor(min(min(app.currentImage))));
            x2 = double(round(max(max(app.currentImage))));
            bins = 512;
            cla(app.imageHistogram); 
            histogram(app.currentImage,bins, 'parent', app.imageHistogram);
            set(app.imageHistogram, 'XLim', [x1 x2]);
            hold(app.imageHistogram, 'on'); 
            
            updateThreshold(app);
            
        end
        
        function app = updateThreshold(app)
            x1 = double(floor(min(min(app.currentImage))));
            x2 = double(round(max(max(app.currentImage))));
            [s1,s2] = size(app.currentImage);
            
            % update threshold range
            if app.thresholdvalueSpinner.Value < x1 || app.thresholdvalueSpinner.Value > x2
                app.thresholdvalueSpinner.Value = mean(mean(app.currentImage));
            end
            app.thresholdvalueSpinner.Limits = [x1, x2];
            app.thresholdPercent.UserData = sum(sum(app.currentImage < app.thresholdvalueSpinner.Value)) / (s1*s2)*100;
            app.thresholdPercent.Text = [num2str(round(app.thresholdPercent.UserData)),'%'];
            
            % plot 
            children = get(app.imageHistogram,'Children')
            if size(children,1) == 2
               delete(children(1))
            end
            xline(app.imageHistogram, app.thresholdvalueSpinner.Value,'-r', 'linewidth',2);
            set(app.imageHistogram, 'XLim', [x1 x2]);
      
        end
    end
    

    methods (Access = private)

        % Value changed function: thresholdvalueSpinner
        function thresholdvalueSpinnerValueChanged(app, event)
                updateThreshold(app);
        end

        % Button pushed function: LoadImageStackButton
        function LoadImageStackButtonPushed(app, event)
            [file,path] = uigetfile('*.tif'); 
            
            if ~file
                return
            end
            % image file path
            app.imageFilePath.Value = file; 
            app.imageFilePath.UserData = [path,file]; 
            app.originalImage = loadTiffStack([path,file]); 
            
            % reset frames for averaging;
            app.StartEditField.Value = 1;
            app.StopEditField.Value = 100;
            app.StopEditField.Limits = [1, size(app.originalImage,3)];
            
            % unclick subtract background 
            app.SubtractBackgroundCheckBox.Value = 0; 
            
            % update gui with new info
            updatePreview(app); 
        end

        % Value changed function: SubtractBackgroundCheckBox
        function SubtractBackgroundCheckBoxValueChanged(app, event)
            value = app.SubtractBackgroundCheckBox.Value
            updatePreview(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.imageHistogram = uiaxes(app.UIFigure);
            title(app.imageHistogram, '')
            xlabel(app.imageHistogram, 'Counts')
            app.imageMaskPreview = uiaxes(app.UIFigure);
            title(app.imageMaskPreview, '')
            app.imageMaskPreview.XTick = [];
            app.imageMaskPreview.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.imageFilePath = uieditfield(app.UIFigure, 'text');
            app.LoadImageStackButton = uibutton(app.UIFigure, 'push');
            app.LoadImageStackButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageStackButtonPushed, true);
            app.LoadImageStackButton.Text = 'Load Image Stack';
            app.thresholdvalueSpinnerLabel = uilabel(app.UIFigure);
            app.thresholdvalueSpinnerLabel.Text = 'threshold value';
            app.thresholdvalueSpinner = uispinner(app.UIFigure);
            app.thresholdvalueSpinner.Limits = [0 Inf];
            app.thresholdvalueSpinner.RoundFractionalValues = 'on';
            app.thresholdvalueSpinner.ValueChangedFcn = createCallbackFcn(app, @thresholdvalueSpinnerValueChanged, true);
            app.thresholdPercent = uilabel(app.UIFigure);
            app.thresholdPercent.Text = {'0%'; ''};
            app.SaveImageMaskButton = uibutton(app.UIFigure, 'push');
            app.SaveImageMaskButton.Text = 'Save Image Mask';
            app.Panel = uipanel(app.UIFigure);
            app.AverageFramesButton = uibutton(app.Panel, 'state');
            app.AverageFramesButton.Text = 'Average Frames';
            app.StartEditFieldLabel = uilabel(app.Panel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.Panel, 'numeric');
            app.StartEditField.Limits = [1 Inf];
            app.StartEditField.Value = 1;
            app.StopEditFieldLabel = uilabel(app.Panel);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.Panel, 'numeric');
            app.StopEditField.Limits = [1 Inf];
            app.StopEditField.Value = 100;
            app.SubtractBackgroundCheckBox = uicheckbox(app.Panel);
            app.SubtractBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractBackgroundCheckBoxValueChanged, true);
            app.SubtractBackgroundCheckBox.Text = 'Subtract Background';
            app.ResetImageButton = uibutton(app.UIFigure, 'push');
            app.ResetImageButton.Text = 'Reset Image';
            app.UpateImageButton = uibutton(app.UIFigure, 'push');
            app.UpateImageButton.Text = 'Upate Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = testmask

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end